classdef PCB_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        HomePanel                       matlab.ui.container.Panel
        UserGuideButton                 matlab.ui.control.Button
        GraphAnalyzerButton             matlab.ui.control.Button
        TracePropertiesButton           matlab.ui.control.Button
        ImpedanceCalculatorButton       matlab.ui.control.Button
        ResonantFrequencyCalculatorButton  matlab.ui.control.Button
        StartOperation                  matlab.ui.control.Label
        UnitConverterButton             matlab.ui.control.Button
        PCBDesignToolLabel              matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        MaterialSelectorPanel           matlab.ui.container.Panel
        BackButton                      matlab.ui.control.Button
        SelectButton                    matlab.ui.control.Button
        ListBox                         matlab.ui.control.ListBox
        SubstrateSelection              matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        ResonantFrequencyPanel          matlab.ui.container.Panel
        HomeButton_6                    matlab.ui.control.Button
        BackButton_7                    matlab.ui.control.Button
        Confirmation                    matlab.ui.control.Label
        ConfirmButton                   matlab.ui.control.Button
        Height                          matlab.ui.control.NumericEditField
        HeightofthesubstrateinmmhEditFieldLabel  matlab.ui.control.Label
        Width                           matlab.ui.control.NumericEditField
        WidthofthemicrostriplineinmmWEditFieldLabel  matlab.ui.control.Label
        Length                          matlab.ui.control.NumericEditField
        LengthofthemicrostriplineinmmLLabel  matlab.ui.control.Label
        SubstrateConf                   matlab.ui.control.TextArea
        YouhavechosenthefollowingsubstrateTextAreaLabel  matlab.ui.control.Label
        Image_3                         matlab.ui.control.Image
        ResoFreqResult                  matlab.ui.container.Panel
        GenerateButton                  matlab.ui.control.Button
        HomeButton_7                    matlab.ui.control.Button
        BackButton_8                    matlab.ui.control.Button
        Result                          matlab.ui.control.TextArea
        TheResonantFrequencyObtainedisTextAreaLabel  matlab.ui.control.Label
        Image_4                         matlab.ui.control.Image
        UnitConverterPanel              matlab.ui.container.Panel
        GeneratePDFButton               matlab.ui.control.Button
        BackButton_11                   matlab.ui.control.Button
        ResultTextArea                  matlab.ui.control.TextArea
        ResultTextAreaLabel             matlab.ui.control.Label
        ConvertButton                   matlab.ui.control.Button
        LengthDropDown                  matlab.ui.control.DropDown
        TraceLengthEditField            matlab.ui.control.NumericEditField
        TraceLengthEditFieldLabel       matlab.ui.control.Label
        ThicknessDropDown               matlab.ui.control.DropDown
        TraceThicknessEditField         matlab.ui.control.NumericEditField
        TraceThicknessEditFieldLabel    matlab.ui.control.Label
        WidthDropDown                   matlab.ui.control.DropDown
        TraceWidthEditField             matlab.ui.control.NumericEditField
        TraceWidthEditFieldLabel        matlab.ui.control.Label
        EnterthegivenvaluesLabel        matlab.ui.control.Label
        Image_5                         matlab.ui.control.Image
        ImpedanceCalculator             matlab.ui.container.Panel
        GeneratePDFButton_2             matlab.ui.control.Button
        HomeButton                      matlab.ui.control.Button
        BackButton_2                    matlab.ui.control.Button
        ImpedanceResultsTextArea        matlab.ui.control.TextArea
        ImpedanceResultsTextAreaLabel   matlab.ui.control.Label
        CalculateButton_2               matlab.ui.control.Button
        TraceTypeDropDown               matlab.ui.control.DropDown
        TraceTypeDropDownLabel          matlab.ui.control.Label
        FrequencyinMHzEditField         matlab.ui.control.NumericEditField
        FrequencyinMHzEditFieldLabel    matlab.ui.control.Label
        HeightofSubstrateinmm           matlab.ui.control.NumericEditField
        HeightofSubstrateinmmEditFieldLabel  matlab.ui.control.Label
        TraceThicknessozsqft            matlab.ui.control.NumericEditField
        TraceThicknessinozsqftEditField_2Label  matlab.ui.control.Label
        TraceWidthmils                  matlab.ui.control.NumericEditField
        TraceWidthinmilsEditField_2Label  matlab.ui.control.Label
        SubConf                         matlab.ui.control.TextArea
        YouhavechosenthefollowingsubstrateTextAreaLabel_3  matlab.ui.control.Label
        Image_6                         matlab.ui.control.Image
        TraceProperties                 matlab.ui.container.Panel
        HomeButton_2                    matlab.ui.control.Button
        BackButton_3                    matlab.ui.control.Button
        Currentcarrying                 matlab.ui.control.Button
        question                        matlab.ui.control.Label
        VoltageDrop                     matlab.ui.control.Button
        TraceWidth                      matlab.ui.control.Button
        SubstrateConf_TP                matlab.ui.control.TextArea
        YouhavechosenthefollowingsubstrateTextAreaLabel_2  matlab.ui.control.Label
        Image_7                         matlab.ui.control.Image
        TraceWidthPanel                 matlab.ui.container.Panel
        GeneratePDFButton_3             matlab.ui.control.Button
        HomeButton_5                    matlab.ui.control.Button
        BackButton_6                    matlab.ui.control.Button
        HFResultsTextArea               matlab.ui.control.TextArea
        HighFrequencyResultsTextAreaLabel  matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        FrequencyMHzEditFieldLabel      matlab.ui.control.Label
        TraceWidthResultsTextArea       matlab.ui.control.TextArea
        TraceWidthResultsTextAreaLabel  matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        HighFreqCheckBox                matlab.ui.control.CheckBox
        LayerTypeDropDown               matlab.ui.control.DropDown
        LayerTypeDropDownLabel          matlab.ui.control.Label
        TempRiseEditField               matlab.ui.control.NumericEditField
        TemperatureRiseCEditFieldLabel  matlab.ui.control.Label
        CopperThicknessEditField        matlab.ui.control.NumericEditField
        CopperThicknessozsqftLabel      matlab.ui.control.Label
        CurrentEditField                matlab.ui.control.NumericEditField
        CurrentAEditFieldLabel          matlab.ui.control.Label
        ParametersLabel                 matlab.ui.control.Label
        Image_8                         matlab.ui.control.Image
        VoltageDropPanel                matlab.ui.container.Panel
        GeneratePDFButton_4             matlab.ui.control.Button
        HomeButton_4                    matlab.ui.control.Button
        BackButton_5                    matlab.ui.control.Button
        TraceWidthField                 matlab.ui.control.NumericEditField
        TraceWidthmilsEditFieldLabel    matlab.ui.control.Label
        Resistance                      matlab.ui.control.TextArea
        ResistanceLabel                 matlab.ui.control.Label
        Frequency                       matlab.ui.control.NumericEditField
        FrequencyMHzEditFieldLabel_2    matlab.ui.control.Label
        VoltageDropandPowerLoss         matlab.ui.control.TextArea
        VoltageDropandPowerLossTextAreaLabel  matlab.ui.control.Label
        Calculate                       matlab.ui.control.Button
        HighFreqBox                     matlab.ui.control.CheckBox
        LayerType                       matlab.ui.control.DropDown
        LayerTypeDropDown_2Label        matlab.ui.control.Label
        TraceLength                     matlab.ui.control.NumericEditField
        TraceLengthLabel                matlab.ui.control.Label
        CopperThickness                 matlab.ui.control.NumericEditField
        CopperThicknessozsqftLabel_2    matlab.ui.control.Label
        Current                         matlab.ui.control.NumericEditField
        CurrentAEditFieldLabel_2        matlab.ui.control.Label
        Parameters                      matlab.ui.control.Label
        Image_9                         matlab.ui.control.Image
        CurrentCarrying                 matlab.ui.container.Panel
        GeneratePDFButton_5             matlab.ui.control.Button
        HomeButton_3                    matlab.ui.control.Button
        BackButton_4                    matlab.ui.control.Button
        CurrentCapacityTextArea         matlab.ui.control.TextArea
        CurrentCapacityTextAreaLabel    matlab.ui.control.Label
        Calculate2                      matlab.ui.control.Button
        Frequency2                      matlab.ui.control.NumericEditField
        FrequencyinMHzLabel             matlab.ui.control.Label
        AllowedTemperatureRiseinCEditField  matlab.ui.control.NumericEditField
        AllowedTemperatureRiseinCEditFieldLabel  matlab.ui.control.Label
        TraceTypeDropDown_2             matlab.ui.control.DropDown
        TraceTypeDropDown_2Label        matlab.ui.control.Label
        TraceLengthinmmEditField        matlab.ui.control.NumericEditField
        TraceLengthinmmEditFieldLabel   matlab.ui.control.Label
        TraceThicknessinozsqftEditField  matlab.ui.control.NumericEditField
        TraceThicknessinozsqftEditFieldLabel  matlab.ui.control.Label
        TraceWidthinmilsEditField       matlab.ui.control.NumericEditField
        TraceWidthinmilsEditFieldLabel  matlab.ui.control.Label
        EnterTheValuesGivenBelowLabel   matlab.ui.control.Label
        Image_10                        matlab.ui.control.Image
        GraphAnalyser                   matlab.ui.container.Panel
        BackButton_9                    matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        GraphTypeDropDown               matlab.ui.control.DropDown
        TypeofGraphDropDownLabel        matlab.ui.control.Label
        Title                           matlab.ui.control.Label
        Image_11                        matlab.ui.control.Image
        GraphAnalyserResult             matlab.ui.container.Panel
        HomeButton_8                    matlab.ui.control.Button
        BackButton_10                   matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        PermittivityValue % Description
        UserAction % Description
        Recommendation % Description 
    end
methods (Access = private)


    function resonantFrequency = calculateResonantFrequency(app)
    % Get user inputs in mm from the app
    L_mm = app.Length.Value;      % Length in mm
    W_mm = app.Width.Value;       % Width in mm
    h_mm = app.Height.Value;      % Height in mm
    epsilon_r = app.PermittivityValue;     % Relative permittivity (from substrate selection)

    % Convert mm to meters
    L = L_mm / 1000;
    W = W_mm / 1000;
    h = h_mm / 1000;

    % Speed of light (m/s)
    c = 3e8;

    % Calculate Effective Dielectric Constant (ε_eff)
    epsilon_eff = (epsilon_r + 1)/2 + ((epsilon_r - 1)/2) * (1 / sqrt(1 + 12 * (h / W)));

    % Calculate Resonant Frequency (Hz)
    resonantFrequency = c / (2 * L * sqrt(epsilon_eff));

    % Display the result in the app in GHz
    message = sprintf('Resonant Frequency: %.2f GHz', resonantFrequency / 1e9);
    app.Result.Value = message;
end

    % Helper function to get substrate selection from ListBox
    function selectedSubstrate = getSelectedSubstrate(app)
        selectedSubstrate = app.ListBox.Value;
    end

    % Helper function to update TextArea based on selection
    function updateTextArea(app, selectedSubstrate)
        switch selectedSubstrate
            case 'FR-4'
                permittivity = 4.4;
            case 'Rogers RO4350B'
                permittivity = 3.48;
            case 'Alumina Ceramic'
                permittivity = 9.8;
            case 'Teflon (PTFE)'
                permittivity = 2.1;
            otherwise
                permittivity = NaN;
        end
        app.PermittivityValue = permittivity;
        message = sprintf('You selected %s with εr = %.2f', selectedSubstrate, permittivity);
        if strcmp(app.UserAction, 'ResonantFrequency')
               app.SubstrateConf.Value = message;
            elseif strcmp(app.UserAction, 'TraceProperties')
               app.SubstrateConf_TP.Value = message;
           elseif strcmp(app.UserAction, 'ImpedanceCalculator')
               app.SubConf.Value = message;
           else
               uialert(app.UIFigure, 'Unknown action selected!', 'Error');
        end

        app.SubstrateConf.Value = message;
    end


end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TracePropertiesButton
        function TracePropertiesButtonPushed(app, event)
            app.UserAction = 'TraceProperties';
            app.HomePanel.Visible = 'off';
            app.MaterialSelectorPanel.Visible = 'on';
        end

        % Button pushed function: ResonantFrequencyCalculatorButton
        function ResonantFrequencyCalculatorButtonPushed(app, event)
            app.UserAction = 'ResonantFrequency';
            app.HomePanel.Visible = 'off';
            app.MaterialSelectorPanel.Visible = 'on';
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            
            selectedSubstrate = getSelectedSubstrate(app);
        
            updateTextArea(app, selectedSubstrate);
        
            app.MaterialSelectorPanel.Visible = 'off';
        
            if strcmp(app.UserAction, 'ResonantFrequency')
               app.ResonantFrequencyPanel.Visible = 'on';
            elseif strcmp(app.UserAction, 'TraceProperties')
               app.TraceProperties.Visible = 'on';
           elseif strcmp(app.UserAction, 'ImpedanceCalculator')
               app.ImpedanceCalculator.Visible = 'on';
           else
               uialert(app.UIFigure, 'Unknown action selected!', 'Error');
           end
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            app.ResonantFrequencyPanel.Visible = 'off';
            app.ResoFreqResult.Visible = 'on';
            calculateResonantFrequency(app);

        end

        % Button pushed function: ImpedanceCalculatorButton
        function ImpedanceCalculatorButtonPushed(app, event)
            app.UserAction = 'ImpedanceCalculator';
            app.HomePanel.Visible = 'off';
            app.MaterialSelectorPanel.Visible = 'on';
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % --- Get user inputs ---
    current = app.CurrentEditField.Value; % in Amps
    copper_thickness_oz = app.CopperThicknessEditField.Value; % in oz
    temp_rise = app.TempRiseEditField.Value; % in °C
    layer_type = app.LayerTypeDropDown.Value; % 'Internal' or 'External'
    high_freq_enabled = app.HighFreqCheckBox.Value; % true/false
    frequency = app.FrequencyEditField.Value; % MHz (user input)
    
    
    % --- IPC-2221 constants ---
    if strcmp(layer_type, 'External')
        k = 0.048;
        b = 0.44;
        c = 0.725;
    elseif strcmp(layer_type, 'Internal')
        k = 0.024;
        b = 0.44;
        c = 0.725;
    else
        uialert(app.UIFigure, 'Invalid layer type selected!', 'Error');
        return;
    end
    
    % --- Calculate trace area in sq mils ---
    % Formula: Width (mil) = (Current / (k * (TempRise^b))) ^ (1/c)
    trace_area = (current / (k * (temp_rise^b)))^(1/c);
    
    % --- Convert to mm ---
    trace_width_mils = trace_area/((copper_thickness_oz)*1.378);

    
    % --- High Frequency Considerations ---
    hf_correction_factor = 1.0; % default no correction
    
    if high_freq_enabled
        % Constants for Copper
        mu = 4 * pi * 1e-7;           % Permeability of free space (H/m)
        sigma_cu = 5.8e7;               % Conductivity of copper (S/m)
        % Skin Depth Calculation (in meters)
        delta = sqrt(1 / (pi * frequency * mu * sigma_cu));
        % Convert to microns for easier reading
        delta_microns = delta * 1e3;
        % Display the result
        if delta_microns < 5
            recommendation = 'Consider increasing trace width or using silver/gold plating for high-frequency performance.';
            elseif delta_microns < 20
            recommendation = 'Ensure your trace width accommodates skin effect losses.';
            else
            recommendation = 'Standard copper trace is sufficient at this frequency.';
        end

        % Display recommendation


        % Convert trace width to meters
        trace_width_m = trace_width_mils * 2.54e-5;
        % Effective area (in square meters)
        A_effective = trace_width_m * delta;
        % Convert to square mm for display
        A_effective_mm2 = A_effective * 1e6;
        % Display the result

        % For high-frequency PCB, consider skin effect (simple correction)
        % Assuming increased width by 10% as a factor (for illustration)
        hf_correction_factor = 1.1;
        app.HFResultsTextArea.Value = {
            sprintf('High-Frequency Mode Enabled:\n')
            sprintf('- Skin Depth: %.2f μm at %.2f MHz\n', delta_microns, frequency)
            sprintf('-%s\n', recommendation)
            sprintf('-Effective Area: %.4f mm^2\n', A_effective_mm2);
            sprintf('- Consider wider traces or thicker copper to mitigate skin effect.\n');
        };
    end
    
    % Apply correction
    final_trace_width_mils = trace_width_mils * hf_correction_factor;
    
    
    % --- Display results ---
    result_message = sprintf(['Recommended Trace Width (basic): %.2f mils\n'...
                              'Recommended Trace Width (High Frequency): %.2f mils\n'], ...
                              trace_width_mils, final_trace_width_mils);
    
    app.TraceWidthResultsTextArea.Value = result_message;
        end

        % Button pushed function: TraceWidth
        function TraceWidthButtonPushed(app, event)
            app.UserAction = 'TraceWidth';
            app.TraceProperties.Visible = 'off';
            app.TraceWidthPanel.Visible = 'on';
        end

        % Button pushed function: VoltageDrop
        function VoltageDropButtonPushed(app, event)
            app.UserAction = 'VoltageDrop';
            app.TraceProperties.Visible = 'off';
            app.VoltageDropPanel.Visible = 'on';
        end

        % Button pushed function: Calculate
        function CalculateButtonPushed2(app, event)
            % User inputs from app components
            current = app.Current.Value;                % Current in Amps
            copper_thickness = app.CopperThickness.Value;      % Copper thickness in oz/ft²
            trace_length_mm = app.TraceLength.Value;        % Length in mm
            trace_width_mils = app.TraceWidthField.Value;        % Trace width in mils
            trace_type = app.LayerType.Value;                % 'Internal' or 'External'
            high_freq_enabled = app.HighFreqBox.Value;
            frequency_MHz = app.Frequency.Value; % MHz
            
            % Conversion constants
            mil_to_meter = 25.4e-6;
            frequency_Hz = frequency_MHz * 1e6;


            % Convert user inputs to meters
            trace_length_m = trace_length_mm / 1000; % mm to meters
            trace_width_m = trace_width_mils * mil_to_meter;
            
            % Convert copper weight (oz/ft²) to thickness in mils then meters
            thickness_mils = 1.37 * copper_thickness; % mils
            trace_thickness_m = thickness_mils * mil_to_meter; % meters
            
            % Cross-sectional area of the trace in m²
            A = trace_width_m * trace_thickness_m;
            A_mm2 = A * 1e+06;
            
            % Validate area
            if A_mm2 == 0
                uialert(app.UIFigure, 'Error: Trace width or thickness cannot be zero.', 'Error');
                return;
            end
            
            % Copper resistivity
            copper_resistivity = 1.68e-8; % Ω·m


            if high_freq_enabled
                mu0 = 4 * pi * 1e-7;    % H/m
                rho_copper = 1.68e-8;   % Ω·m


                switch trace_type
                case 'Internal'
                    mu_eff = mu0 * 1.1;
                case 'External'
                    mu_eff = mu0;
                otherwise
                    uialert(app.UIFigure, 'Unknown action selected!', 'Error');
                    return;
               end


                delta = sqrt(2 * rho_copper / (mu_eff * 2 * pi * frequency_Hz)); % in meters
                % Effective thickness is minimum of actual thickness and skin depth
                effective_thickness_m = min(trace_thickness_m, delta);
                % Effective area with skin effect consideration
                A_eff = trace_width_m * effective_thickness_m;
                A_eff_mm2 = A_eff * 1e+06;
                
                % Recalculate resistance
                R_skin = copper_resistivity * (trace_length_m / A_eff);
                R_skin_mo = R_skin * 1e3;


                % Voltage drop & power loss with skin effect resistance
                Vdrop_skin = current * R_skin;
                Vdrop_skin_mv = Vdrop_skin * 1e3;
                PowerLoss_skin = current^2 * R_skin;
            end


            % Apply derating for internal/external traces
            switch trace_type
                case 'Internal'
                    derating_factor = 0.8; % Internal traces need derating due to less cooling
                case 'External'
                    derating_factor = 1.0; % External traces are better cooled
                otherwise
                    uialert(app.UIFigure, 'Unknown action selected!', 'Error');
                    return;
            end
            
            % Calculate resistance (Ohms)
            R = copper_resistivity * (trace_length_m / A);
            
            % Apply derating if internal trace
            R = R / derating_factor; 
            R_mo = R * 1e3;
            
            % Voltage drop (V)
            Vdrop = current * R;
            Vdrop_mv = Vdrop * 1e3;
            
            % Power loss (W)
            PowerLoss = current^2 * R;
            
            % Display results in the app
            % Initialize output as a cell array
            outputText = {
                sprintf('--- Trace Electrical Properties ---')
                sprintf('Trace Type: %s', trace_type)
                sprintf('Cross-sectional Area (Basic): %.6e mm²', A_mm2)
                sprintf('Resistance (Basic): %.6f mΩ', R_mo)
            };
            
            % Add high-frequency values if enabled
            if high_freq_enabled
                outputText = [outputText; {  % Append new lines to the cell array
                    sprintf('High Frequency Mode: Enabled')
                    sprintf('Cross-sectional Area (High Frequency): %.6e mm²', A_eff_mm2)
                    sprintf('Resistance (High Frequency): %.6f mΩ', R_skin_mo)
                }];
            end

            % Assign to UI component
            app.Resistance.Value = outputText;


            outputText1 = {
                sprintf('--- Trace Electrical Properties ---')
                sprintf('Trace Type: %s', trace_type)
                sprintf('Voltage Drop (Basic): %.4f mV', Vdrop_mv)
                sprintf('Power Loss (Basic): %.4f W', PowerLoss)
            };
            
            % Add high-frequency values if enabled
            if high_freq_enabled
                outputText1 = [outputText1; {  % Append new lines to the cell array
                    sprintf('High Frequency Mode: Enabled')
                    sprintf('Voltage Drop (High Frequency): %.4f mV', Vdrop_skin_mv)
                    sprintf('Power Loss (High Frequency): %.4f W', PowerLoss_skin)
                }];
            end

            % Assign to UI component
            app.VoltageDropandPowerLoss.Value = outputText1;
        end

        % Button pushed function: Calculate2
        function Calculate2ButtonPushed(app, event)
            % User Inputs
            trace_width_mils = app.TraceWidthinmilsEditField.Value;  % in mils
            trace_thickness_oz = app.TraceThicknessinozsqftEditField.Value;  % in oz/ft²
            temp_rise = app.AllowedTemperatureRiseinCEditField.Value; % in °C
            is_internal = app.TraceTypeDropDown_2.Value; % 'Internal' or 'External'
            frequency_MHz = app.Frequency2.Value; % Frequency in MHz
            
            % Convert trace width to mm
            trace_width_mm = trace_width_mils * 0.0254; 
            
            % Convert thickness from oz/ft² to mm (1 oz/ft² ≈ 35 μm)
            trace_thickness_mm = trace_thickness_oz * 35 * 1e-3; 
            
            % IPC-2221 Equations
            if strcmp(is_internal, 'Internal')
                k = 0.024;  % Internal trace constant
                b = 0.44;
                c = 0.725;
            else
                k = 0.048;  % External trace constant
                b = 0.44;
                c = 0.725;
            end
            
            % Calculate current capacity (I) using IPC-2221 formula:
            I_max = k * (trace_width_mm ^ b) * (trace_thickness_mm ^ c) * (temp_rise ^ 0.44);
        

            % Calculate Skin Depth (in mm)
            f_Hz = frequency_MHz * 1e6;  % Convert MHz to Hz
            mu_0 = 4 * pi * 1e-7; % Permeability of free space
            sigma = 5.8e7; % Conductivity of copper (S/m)
            
            delta_m = 1 / sqrt(pi * f_Hz * mu_0 * sigma); % Skin depth in meters
            delta_um = delta_m * 1e6; % Convert to microns for display
        
            % Adjust Effective Thickness Due to Skin Effect
            %if trace_thickness_mm > delta_m
               % effective_thickness_mm = delta_m; % Only the skin depth contributes
            %else
              %  effective_thickness_mm = trace_thickness_mm; % Use full thickness at low freq
           % end


            %min_thickness_mm = 1e-6; % Minimum threshold (1 micron)
            
           % if trace_thickness_mm > delta_m
                %effective_thickness_mm = max(delta_m, min_thickness_mm); % Use skin depth but ensure minimum value
            %else
               % effective_thickness_mm = max(trace_thickness_mm, min_thickness_mm); % Use full thickness at low frequency
            %end


            app.CurrentCapacityTextArea.Value = sprintf( ...
            "Estimated Max Current Capacity: %.2f A\nSkin Depth at %.2f MHz: %.2f µm\n", ...
            I_max, frequency_MHz, delta_um);


        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            % Get user inputs from the GUI components
            W_mils = app.TraceWidthmils.Value;    % Trace width (mils)
            T_oz = app.TraceThicknessozsqft.Value;  % Trace thickness (oz/ft²)
            H_mm = app.HeightofSubstrateinmm.Value; % Substrate height (mm)
            f_MHz = app.FrequencyinMHzEditField.Value; % Frequency (MHz)
            traceType = app.TraceTypeDropDown.Value; % Microstrip or Stripline
            er = app.PermittivityValue;
        
            % Convert frequency to Hz
            f_Hz = f_MHz * 1e6;
        
            % Convert trace width from mils to mm (1 mil = 0.0254 mm)
            W_mm = W_mils * 0.0254;
        
            % Convert trace thickness from oz/ft² to mm (1 oz/ft² ≈ 0.0348 mm)
            T_mm = T_oz * 0.0348;
        
            % Calculate effective permittivity for microstrip
            er_eff = (er + 1)/2 + ((er - 1)/2) * (1 / sqrt(1 + 12 * (H_mm/W_mm)));
        
            % Calculate characteristic impedance
            if strcmp(traceType, 'Microstrip')
                % Hammerstad & Jensen's equation for Microstrip Impedance
                if (W_mm/H_mm <= 1)
                    Z0 = (60 / sqrt(er_eff)) * log(8*H_mm/W_mm + 0.25*(W_mm/H_mm));
                else
                    Z0 = (120 * pi) / (sqrt(er_eff) * (W_mm/H_mm + 1.393 + 0.667*log(W_mm/H_mm + 1.444)));
                end
            elseif strcmp(traceType, 'Stripline')
                % Stripline impedance formula
                Z0 = (60 / sqrt(er)) * log((4*H_mm) / (0.67 * pi * (T_mm + 1.1 * W_mm)));
            else
                Z0 = NaN; % Invalid selection
            end
        
            % High-frequency skin effect correction
            mu_0 = 4 * pi * 1e-7; % Permeability of free space (H/m)
            sigma_cu = 5.8e7; % Conductivity of copper (S/m)
            delta_m = 1 / sqrt(pi * f_Hz * mu_0 * sigma_cu); % Skin depth (m)
            delta_mm = delta_m * 1e3; % Convert to mm
        
            % Adjust impedance for high frequencies (if applicable)
            if f_MHz > 100  % Apply correction above 100 MHz
                W_eff_mm = max(W_mm - delta_mm, W_mm * 0.9); % Reduce width due to skin effect
                if strcmp(traceType, 'Microstrip')
                    Z0 = (60 / sqrt(er_eff)) * log(8*H_mm/W_eff_mm + 0.25*(W_eff_mm/H_mm));
                elseif strcmp(traceType, 'Stripline')
                    Z0 = (60 / sqrt(er)) * log((4*H_mm) / (0.67 * pi * (T_mm + 1.1 * W_eff_mm)));
                end
            end
        
            % Display results in GUI
            app.ImpedanceResultsTextArea.Value = sprintf( ...
                "Estimated Impedance: %.2f Ω\nEffective Permittivity: %.2f\nSkin Depth at %.2f MHz: %.3f mm\nConverted Width: %.2f mm\nConverted Thickness: %.3f mm", ...
                Z0, er_eff, f_MHz, delta_mm, W_mm, T_mm);
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.MaterialSelectorPanel.Visible = 'off';
            app.HomePanel.Visible = 'on';  
        end

        % Button pushed function: BackButton_2
        function BackButton_2Pushed(app, event)
            app.ImpedanceCalculator.Visible = 'off';
            app.MaterialSelectorPanel.Visible = 'on'; 
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            app.ImpedanceCalculator.Visible = 'off';
            app.HomePanel.Visible = 'on'; 
        end

        % Button pushed function: BackButton_3
        function BackButton_3Pushed(app, event)
            app.TraceProperties.Visible = 'off';
            app.MaterialSelectorPanel.Visible = 'on'; 
        end

        % Button pushed function: HomeButton_2
        function HomeButton_2Pushed(app, event)
            app.TraceProperties.Visible = 'off';
            app.HomePanel.Visible = 'on'; 
        end

        % Button pushed function: BackButton_4
        function BackButton_4Pushed(app, event)
            app.CurrentCarrying.Visible = 'off';
            app.TraceProperties.Visible = 'on'; 
        end

        % Button pushed function: HomeButton_3
        function HomeButton_3Pushed(app, event)
            app.CurrentCarrying.Visible = 'off';
            app.HomePanel.Visible = 'on';
        end

        % Button pushed function: BackButton_5
        function BackButton_5Pushed(app, event)
            app.VoltageDropPanel.Visible = 'off';
            app.TraceProperties.Visible = 'on';
        end

        % Button pushed function: HomeButton_4
        function HomeButton_4Pushed(app, event)
            app.VoltageDropPanel.Visible = 'off';
            app.HomePanel.Visible = 'on';
        end

        % Button pushed function: BackButton_6
        function BackButton_6Pushed(app, event)
            app.TraceWidthPanel.Visible = 'off';
            app.TraceProperties.Visible = 'on';
        end

        % Button pushed function: HomeButton_5
        function HomeButton_5Pushed(app, event)
            app.TraceWidthPanel.Visible = 'off';
            app.HomePanel.Visible = 'on';
        end

        % Button pushed function: BackButton_7
        function BackButton_7Pushed(app, event)
            app.ResonantFrequencyPanel.Visible = 'off';
            app.MaterialSelectorPanel.Visible = 'on';
        end

        % Button pushed function: HomeButton_6
        function HomeButton_6Pushed(app, event)
            app.ResonantFrequencyPanel.Visible = 'off';
            app.HomePanel.Visible = 'on';
        end

        % Button pushed function: BackButton_8
        function BackButton_8Pushed(app, event)
            app.ResoFreqResult.Visible = 'off';
            app.ResonantFrequencyPanel.Visible = 'on';
        end

        % Button pushed function: HomeButton_7
        function HomeButton_7Pushed(app, event)
            app.ResoFreqResult.Visible = 'off';
            app.HomePanel.Visible = 'on';
        end

        % Button pushed function: Currentcarrying
        function CurrentcarryingButtonPushed(app, event)
            app.TraceProperties.Visible = 'off';
            app.CurrentCarrying.Visible = 'on';
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            % Get user inputs
            W_value = app.TraceWidthEditField.Value;
            W_unit = app.WidthDropDown.Value;
            
            T_value = app.TraceThicknessEditField.Value;
            T_unit = app.ThicknessDropDown.Value;
            
            L_value = app.TraceLengthEditField.Value;
            L_unit = app.LengthDropDown.Value;
            
            % Convert Trace Width to mm
            switch W_unit
                case 'mils'
                    W_mm = W_value * 0.0254; % 1 mil = 0.0254 mm
                    W_inches = W_value * 0.001; 
                    W_mils = W_value;
                case 'inches'
                    W_mm = W_value * 25.4; % 1 inch = 25.4 mm
                    W_mils = W_value * 1000;
                    W_inches = W_value;
                case 'mm'
                    W_mm = W_value; % Already in mm
                    W_inches = W_value * 0.0393701;
                    W_mils = W_value * 39.3701;
                otherwise
                    W_mm = NaN;
            end
        
            % Convert Trace Thickness to mm
            switch T_unit
                case 'oz/sq ft'
                    T_mm = T_value * 0.0348; % 1 oz/ft² ≈ 0.0348 mm
                    T_mils = T_value * 1.37;
                    T_oz = T_value;
                case 'mils'
                    T_mm = T_value * 0.0254; % 1 mil = 0.0254 mm
                    T_mils = T_value;
                    T_oz = T_value * 0.729927;
                case 'mm'
                    T_mm = T_value; % Already in mm
                    T_mils = T_value * 39.3701;
                    T_oz = T_value * 28.74;
                otherwise
                    T_mm = NaN;
            end
        
            % Convert Trace Length to mm
            switch L_unit
                case 'mils'
                    L_mm = L_value * 0.0254; % 1 cm = 10 mm
                    L_mils = L_value;
                    L_inches = L_value * 0.001;
                case 'inches'
                    L_mm = L_value * 25.4; % 1 inch = 25.4 mm
                    L_mils = L_value * 1000;
                    L_inches = L_value;
                case 'mm'
                    L_mm = L_value; % Already in mm
                    L_mils = L_value * 39.3701;
                    L_inches = L_value * 0.0393701;
                otherwise
                    L_mm = NaN;
            end
        
            % Display results
            app.ResultTextArea.Value = sprintf( ...
                "Converted Trace Width:\n %.3f mm\n %.3f inches\n %.3f mils\nConverted Trace Thickness:\n %.3f mm\n %.3f oz/sq ft\n %.3f mils\nConverted Trace Length:\n %.3f mm\n %.3f mils\n %.3f inches", ...
                W_mm, W_inches, W_mils, T_mm, T_oz, T_mils, L_mm, L_mils, L_inches);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.GraphAnalyser.Visible = 'off';
            app.GraphAnalyserResult.Visible = 'on';


            % Get the selected graph type
            selectedGraph = app.GraphTypeDropDown.Value;

            % Clear previous plot and enable multiple graphs if needed
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
        
            % Define frequency range (log scale for better visualization)
            freq = logspace(1, 8, 100); % 10 Hz to 100 MHz
        
            % Define a sample current range for voltage drop and power loss calculations
            current = linspace(0.1, 10, 100); % 0.1A to 10A
        
            % Example parameters
            trace_width = linspace(0.1, 5, 100); % Width range in mm
            trace_thickness = 35e-6; % 35µm copper thickness (standard 1 oz)
            resistivity_copper = 1.68e-8; % Ohm-m
            trace_length = 50e-3; % 50mm trace length
        
            % Constants for skin depth
            mu = 4 * pi * 1e-7; % Permeability of free space
            sigma = 5.8e7; % Conductivity of copper (S/m)
        
            % Perform calculations based on selected graph
            switch selectedGraph
                case 'Impedance vs Frequency'
                    Z0 = 50 + 10*log10(freq / 1e6); % Example formula for impedance
                    plot(app.UIAxes, freq, Z0, 'b-', 'LineWidth', 2, 'MarkerSize', 5);
                    xlabel(app.UIAxes, 'Frequency (Hz)');
                    ylabel(app.UIAxes, 'Impedance (Ω)');
                    title(app.UIAxes, 'Impedance vs Frequency');
                    grid(app.UIAxes, 'on');
                    set(app.UIAxes, 'XScale', 'log'); % Log scale for better frequency visualization
        
                case 'Skin Depth vs Frequency'
                    delta = 1 ./ sqrt(pi * freq * mu * sigma); % Skin depth formula
                    plot(app.UIAxes, freq, delta * 1e6, 'r-', 'LineWidth', 2, 'MarkerSize', 5);
                    xlabel(app.UIAxes, 'Frequency (Hz)');
                    ylabel(app.UIAxes, 'Skin Depth (µm)');
                    title(app.UIAxes, 'Skin Depth vs Frequency');
                    grid(app.UIAxes, 'on');
                    set(app.UIAxes, 'XScale', 'log'); % Log scale
        
                case 'Voltage Drop vs Current'
                    resistance = resistivity_copper * trace_length ./ (trace_width * trace_thickness); % R = ρL/A
                    voltageDrop = current .* resistance; % V = IR
                    plot(app.UIAxes, current, voltageDrop, 'g-', 'LineWidth', 2, 'MarkerSize', 5);
                    xlabel(app.UIAxes, 'Current (A)');
                    ylabel(app.UIAxes, 'Voltage Drop (V)');
                    title(app.UIAxes, 'Voltage Drop vs Current');
                    grid(app.UIAxes, 'on');
        
                case 'Power Loss vs Trace Width'
                    resistance = resistivity_copper * trace_length ./ (trace_width * trace_thickness);
                    powerLoss = (current.^2) .* resistance; % P = I²R
                    plot(app.UIAxes, trace_width, powerLoss, 'm-', 'LineWidth', 2, 'MarkerSize', 5);
                    xlabel(app.UIAxes, 'Trace Width (mm)');
                    ylabel(app.UIAxes, 'Power Loss (W)');
                    title(app.UIAxes, 'Power Loss vs Trace Width');
                    grid(app.UIAxes, 'on');
        
                case 'Effective Trace Thickness vs Frequency'
                    delta = 1 ./ sqrt(pi * freq * mu * sigma); % Skin depth
                    effective_thickness = min(trace_thickness, delta); % Copper thickness limited by skin depth
                    plot(app.UIAxes, freq, effective_thickness * 1e6, 'c-', 'LineWidth', 2, 'MarkerSize', 5);
                    xlabel(app.UIAxes, 'Frequency (Hz)');
                    ylabel(app.UIAxes, 'Effective Trace Thickness (µm)');
                    title(app.UIAxes, 'Effective Trace Thickness vs Frequency');
                    grid(app.UIAxes, 'on');
                    set(app.UIAxes, 'XScale', 'log'); % Log scale
        
                otherwise
                    cla(app.UIAxes); % Clear axes if no valid selection
            end


            % Enable Zoom & Pan
            zoom(app.UIAxes, 'on');
            pan(app.UIAxes, 'on');
        
            % Show Grid
            grid(app.UIAxes, 'on');
        
            % Hold off for new plots
            hold(app.UIAxes, 'off');
        end

        % Button pushed function: BackButton_9
        function BackButton_9Pushed(app, event)
            app.GraphAnalyser.Visible = 'off';
            app.HomePanel.Visible = 'on';
        end

        % Button pushed function: BackButton_10
        function BackButton_10Pushed(app, event)
            app.GraphAnalyserResult.Visible = 'off';
            app.GraphAnalyser.Visible = 'on';
        end

        % Button pushed function: HomeButton_8
        function HomeButton_8Pushed(app, event)
            app.GraphAnalyserResult.Visible = 'off';
            app.HomePanel.Visible = 'on';
        end

        % Button pushed function: BackButton_11
        function BackButton_11Pushed(app, event)
            app.UnitConverterPanel.Visible = 'off';
            app.HomePanel.Visible = 'on';
        end

        % Button pushed function: UnitConverterButton
        function UnitConverterButtonPushed(app, event)
            app.HomePanel.Visible = 'off';
            app.UnitConverterPanel.Visible = 'on';
        end

        % Button pushed function: GraphAnalyzerButton
        function GraphAnalyzerButtonPushed(app, event)
            app.HomePanel.Visible = 'off';
            app.GraphAnalyser.Visible = 'on';
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
             % Get input values from UI components
             L_mm = app.Length.Value;
             W_mm = app.Width.Value;
             h_mm = app.Height.Value;
             permittivity = app.PermittivityValue;


            % Convert mm to meters
            L = L_mm / 1000;
            W = W_mm / 1000;
            h = h_mm / 1000;

            % Speed of light (m/s)
            c = 3e8;


             % Calculate Effective Dielectric Constant (ε_eff)
             epsilon_eff = (permittivity + 1)/2 + ((permittivity - 1)/2) * (1 / sqrt(1 + 12 * (h / W)));
             resonantFrequency = c / (2 * L * sqrt(epsilon_eff));


            % Create a unique filename with timestamp
            timestamp = datestr(now, 'yyyy-mm-dd_HH-MM-SS');
            pdfFileName = ['Resonant_Frequency_Report_' timestamp '.pdf'];
        
            % Create PDF document
            import mlreportgen.dom.*;
            doc = Document(pdfFileName, 'pdf');
        
            % Title
            titlePara = Paragraph('Resonant Frequency Report');
            titlePara.Bold = true;
            titlePara.FontSize = '14pt';
            append(doc, titlePara);
            append(doc, HorizontalRule());
        
            % Input Parameters Table
            append(doc, Paragraph('Input Parameters:'));
            inputTable = Table({...
                'Parameter', 'Value', 'Unit'; ...
                'Length', L_mm, 'mm'; ...
                'Width', W_mm, 'mm'; ...
                'Height of Substrate', h_mm, 'mm'; ...
                'Permittivity', permittivity, ''});
            
            % Apply Border to Table
            inputTable.Border = 'solid';
            inputTable.Style = {Width('100%')}; % Ensure it fits within page width
            append(doc, inputTable);
        
            % Output Section
            append(doc, Paragraph('Calculated Resonant Frequency:'));
            freqText = Paragraph(sprintf('%.2f GHz', resonantFrequency / 1e9));
            freqText.Bold = true;
            freqText.FontSize = '12pt';
            append(doc, freqText);
        
            % Close and save the document
            close(doc);
        
            % Notify user
            msgbox(['PDF report saved as: ' pdfFileName], 'Success');
        end

        % Button pushed function: GeneratePDFButton
        function GeneratePDFButtonPushed(app, event)

            % Get input values from UI components
            W_value = app.TraceWidthEditField.Value;
            W_unit = app.WidthDropDown.Value;
            
            T_value = app.TraceThicknessEditField.Value;
            T_unit = app.ThicknessDropDown.Value;
            
            L_value = app.TraceLengthEditField.Value;
            L_unit = app.LengthDropDown.Value;


            % Convert Trace Width to mm
            switch W_unit
                case 'mils'
                    W_mm = W_value * 0.0254; % 1 mil = 0.0254 mm
                    W_inches = W_value * 0.001; 
                    W_mils = W_value;
                case 'inches'
                    W_mm = W_value * 25.4; % 1 inch = 25.4 mm
                    W_mils = W_value * 1000;
                    W_inches = W_value;
                case 'mm'
                    W_mm = W_value; % Already in mm
                    W_inches = W_value * 0.0393701;
                    W_mils = W_value * 39.3701;
                otherwise
                    W_mm = NaN;
            end
        
            % Convert Trace Thickness to mm
            switch T_unit
                case 'oz/sq ft'
                    T_mm = T_value * 0.0348; % 1 oz/ft² ≈ 0.0348 mm
                    T_mils = T_value * 1.37;
                    T_oz = T_value;
                case 'mils'
                    T_mm = T_value * 0.0254; % 1 mil = 0.0254 mm
                    T_mils = T_value;
                    T_oz = T_value * 0.729927;
                case 'mm'
                    T_mm = T_value; % Already in mm
                    T_mils = T_value * 39.3701;
                    T_oz = T_value * 28.74;
                otherwise
                    T_mm = NaN;
            end
        
            % Convert Trace Length to mm
            switch L_unit
                case 'mils'
                    L_mm = L_value * 0.0254; % 1 cm = 10 mm
                    L_mils = L_value;
                    L_inches = L_value * 0.001;
                case 'inches'
                    L_mm = L_value * 25.4; % 1 inch = 25.4 mm
                    L_mils = L_value * 1000;
                    L_inches = L_value;
                case 'mm'
                    L_mm = L_value; % Already in mm
                    L_mils = L_value * 39.3701;
                    L_inches = L_value * 0.0393701;
                otherwise
                    L_mm = NaN;
            end


            % Create a unique filename with timestamp
            timestamp = datestr(now, 'yyyy-mm-dd_HH-MM-SS');
            pdfFileName = ['Unit_Conversion_Report_' timestamp '.pdf'];
        
            % Create PDF document
            import mlreportgen.dom.*;
            doc = Document(pdfFileName, 'pdf');
        
            % Title
            titlePara = Paragraph('Unit Conversion Report');
            titlePara.Bold = true;
            titlePara.FontSize = '14pt';
            append(doc, titlePara);
            append(doc, HorizontalRule());
        
            % Input Parameters Table
            append(doc, Paragraph('Input Parameters:'));
            
            inputData = {...
                'Parameter', 'Value', 'Unit'; ...
                'Trace Width', sprintf('%.2f', W_value), W_unit; ...
                'Trace Thickness', sprintf('%.2f', T_value), T_unit; ...
                'Trace Length', sprintf('%.2f', L_value), L_unit};
            
            inputTable = Table(inputData);
            inputTable.Border = 'solid';
            inputTable.Style = {Width('100%')}; % Ensures full width
            append(doc, inputTable);
            
            % Output Section
            append(doc, Paragraph('Calculated Units:'));
            
            outputData = {...
                'Parameter', 'Value', 'Unit'; ...
                'Trace Width', sprintf('%.2f', W_mm), "mm"; ...
                'Trace Width', sprintf('%.2f', W_inches), "inches"; ...
                'Trace Width', sprintf('%.2f', W_mils), "mils"; ...
                'Trace Thickness', sprintf('%.2f', T_mm), "mm"; ...
                'Trace Thickness', sprintf('%.2f', T_mils), "mils"; ...
                'Trace Thickness', sprintf('%.2f', T_oz), "oz"; ...
                'Trace Length', sprintf('%.2f', L_mm), "mm"; ...
                'Trace Length', sprintf('%.2f', L_mils), "mils"; ...
                'Trace Length', sprintf('%.2f', L_inches), "inches"};
            
            outputTable = Table(outputData);
            outputTable.Border = 'solid';
            outputTable.Style = {Width('100%')}; % Ensures full width
            append(doc, outputTable);


            % Close and save the document
            close(doc);
        
            % Notify user
            msgbox(['PDF report saved as: ' pdfFileName], 'Success');
        end

        % Button pushed function: GeneratePDFButton_2
        function GeneratePDFButton_2Pushed(app, event)
            % Get user inputs from the GUI components
            W_mils = app.TraceWidthmils.Value;    % Trace width (mils)
            T_oz = app.TraceThicknessozsqft.Value;  % Trace thickness (oz/ft²)
            H_mm = app.HeightofSubstrateinmm.Value; % Substrate height (mm)
            f_MHz = app.FrequencyinMHzEditField.Value; % Frequency (MHz)
            traceType = app.TraceTypeDropDown.Value; % Microstrip or Stripline
            er = app.PermittivityValue;
        
            % Convert frequency to Hz
            f_Hz = f_MHz * 1e6;
        
            % Convert trace width from mils to mm (1 mil = 0.0254 mm)
            W_mm = W_mils * 0.0254;
        
            % Convert trace thickness from oz/ft² to mm (1 oz/ft² ≈ 0.0348 mm)
            T_mm = T_oz * 0.0348;
        
            % Calculate effective permittivity for microstrip
            er_eff = (er + 1)/2 + ((er - 1)/2) * (1 / sqrt(1 + 12 * (H_mm/W_mm)));
        
            % Calculate characteristic impedance
            if strcmp(traceType, 'Microstrip')
                % Hammerstad & Jensen's equation for Microstrip Impedance
                if (W_mm/H_mm <= 1)
                    Z0 = (60 / sqrt(er_eff)) * log(8*H_mm/W_mm + 0.25*(W_mm/H_mm));
                else
                    Z0 = (120 * pi) / (sqrt(er_eff) * (W_mm/H_mm + 1.393 + 0.667*log(W_mm/H_mm + 1.444)));
                end
            elseif strcmp(traceType, 'Stripline')
                % Stripline impedance formula
                Z0 = (60 / sqrt(er)) * log((4*H_mm) / (0.67 * pi * (T_mm + 1.1 * W_mm)));
            else
                Z0 = NaN; % Invalid selection
            end
        
            % High-frequency skin effect correction
            mu_0 = 4 * pi * 1e-7; % Permeability of free space (H/m)
            sigma_cu = 5.8e7; % Conductivity of copper (S/m)
            delta_m = 1 / sqrt(pi * f_Hz * mu_0 * sigma_cu); % Skin depth (m)
            delta_mm = delta_m * 1e3; % Convert to mm
        
            % Adjust impedance for high frequencies (if applicable)
            if f_MHz > 100  % Apply correction above 100 MHz
                W_eff_mm = max(W_mm - delta_mm, W_mm * 0.9); % Reduce width due to skin effect
                if strcmp(traceType, 'Microstrip')
                    Z0 = (60 / sqrt(er_eff)) * log(8*H_mm/W_eff_mm + 0.25*(W_eff_mm/H_mm));
                elseif strcmp(traceType, 'Stripline')
                    Z0 = (60 / sqrt(er)) * log((4*H_mm) / (0.67 * pi * (T_mm + 1.1 * W_eff_mm)));
                end
            end


             % Create a unique filename with timestamp
            timestamp = datestr(now, 'yyyy-mm-dd_HH-MM-SS');
            pdfFileName = ['Impedance_Calculation_Report_' timestamp '.pdf'];
        
            % Create PDF document
            import mlreportgen.dom.*;
            doc = Document(pdfFileName, 'pdf');
        
            % Title
            titlePara = Paragraph('Impedance Calculator Report');
            titlePara.Bold = true;
            titlePara.FontSize = '14pt';
            append(doc, titlePara);
            append(doc, HorizontalRule());
        
            % Input Parameters Table
            append(doc, Paragraph('Input Parameters:'));
            
            inputData = {...
                'Parameter', 'Value', 'Unit'; ...
                'Trace Width', sprintf('%.2f', W_mils), "mils"; ...
                'Trace Thickness', sprintf('%.2f', T_oz), "oz/sq ft"; ...
                'Height of Substrate', sprintf('%.2f', H_mm), "mm"; ...
                'Frequency', sprintf('%.2f', f_MHz), "MHz"; ...
                'Trace Type', traceType , " "; ...
                'Permittivity', sprintf('%.2f', er), " "};
            
            inputTable = Table(inputData);
            inputTable.Border = 'solid';
            inputTable.Style = {Width('100%')}; % Ensures full width
            append(doc, inputTable);
            
            % Output Section
            append(doc, Paragraph('Calculated Units:'));
            
            outputData = {...
                'Parameter', 'Value', 'Unit'; ...
                'Estimated Impedance', sprintf('%.2f', Z0), "Ω"; ...
                'Effective Permittivity', sprintf('%.2f', er_eff), " "; ...
                'Skin Depth', sprintf('%.2f', delta_mm), "mm"; ...
                'Converted Width', sprintf('%.2f', W_mm), "mm"; ...
                'Converted Thickness', sprintf('%.2f', T_mm), "mm"};


            outputTable = Table(outputData);
            outputTable.Border = 'solid';
            outputTable.Style = {Width('100%')}; % Ensures full width
            append(doc, outputTable);


            % Close and save the document
            close(doc);
        
            % Notify user
            msgbox(['PDF report saved as: ' pdfFileName], 'Success');
        end

        % Button pushed function: GeneratePDFButton_3
        function GeneratePDFButton_3Pushed(app, event)

             % --- Get user inputs ---
    current = app.CurrentEditField.Value; % in Amps
    copper_thickness_oz = app.CopperThicknessEditField.Value; % in oz
    temp_rise = app.TempRiseEditField.Value; % in °C
    layer_type = app.LayerTypeDropDown.Value; % 'Internal' or 'External'
    high_freq_enabled = app.HighFreqCheckBox.Value; % true/false
    frequency = app.FrequencyEditField.Value; % MHz (user input)
    
    
    % --- IPC-2221 constants ---
    if strcmp(layer_type, 'External')
        k = 0.048;
        b = 0.44;
        c = 0.725;
    elseif strcmp(layer_type, 'Internal')
        k = 0.024;
        b = 0.44;
        c = 0.725;
    else
        uialert(app.UIFigure, 'Invalid layer type selected!', 'Error');
        return;
    end
    
    % --- Calculate trace area in sq mils ---
    % Formula: Width (mil) = (Current / (k * (TempRise^b))) ^ (1/c)
    trace_area = (current / (k * (temp_rise^b)))^(1/c);
    
    % --- Convert to mm ---
    trace_width_mils = trace_area/((copper_thickness_oz)*1.378);

    
    % --- High Frequency Considerations ---
    hf_correction_factor = 1.0; % default no correction
    
    if high_freq_enabled
        % Constants for Copper
        mu = 4 * pi * 1e-7;           % Permeability of free space (H/m)
        sigma_cu = 5.8e7;               % Conductivity of copper (S/m)
        % Skin Depth Calculation (in meters)
        delta = sqrt(1 / (pi * frequency * mu * sigma_cu));
        % Convert to microns for easier reading
        delta_microns = delta * 1e3;
        % Display the result
        if delta_microns < 5
            recommendation = 'Consider increasing trace width or using silver/gold plating for high-frequency performance.';
            elseif delta_microns < 20
            recommendation = 'Ensure your trace width accommodates skin effect losses.';
            else
            recommendation = 'Standard copper trace is sufficient at this frequency.';
        end

        % Display recommendation


        % Convert trace width to meters
        trace_width_m = trace_width_mils * 2.54e-5;
        % Effective area (in square meters)
        A_effective = trace_width_m * delta;
        % Convert to square mm for display
        A_effective_mm2 = A_effective * 1e6;
        % Display the result

        % For high-frequency PCB, consider skin effect (simple correction)
        % Assuming increased width by 10% as a factor (for illustration)
        hf_correction_factor = 1.1;
        
    end
    
    % Apply correction
    final_trace_width_mils = trace_width_mils * hf_correction_factor;


            % Create a unique filename with timestamp
            timestamp = datestr(now, 'yyyy-mm-dd_HH-MM-SS');
            pdfFileName = ['Trace_Width_Calculation_Report_' timestamp '.pdf'];
        
            % Create PDF document
            import mlreportgen.dom.*;
            doc = Document(pdfFileName, 'pdf');
        
            % Title
            titlePara = Paragraph('Trace Width Calculation Report');
            titlePara.Bold = true;
            titlePara.FontSize = '14pt';
            append(doc, titlePara);
            append(doc, HorizontalRule());
        
            % Input Parameters Table
            append(doc, Paragraph('Input Parameters:'));
            
            inputData = {...
                'Parameter', 'Value', 'Unit'; ...
                'Current', sprintf('%.2f', current), "A"; ...
                'Copper Thickness', sprintf('%.2f', copper_thickness_oz), "oz/sq ft"; ...
                'Temperature Rise', sprintf('%.2f', temp_rise), "°C"; ...
                'Layer Type', layer_type, " "; ...
                'High Frequency PCB', high_freq_enabled , " "; ...
                'frequency', sprintf('%.2f', frequency), "MHz"};
            
            inputTable = Table(inputData);
            inputTable.Border = 'solid';
            inputTable.Style = {Width('100%')}; % Ensures full width
            append(doc, inputTable);
            
            % Output Section
            append(doc, Paragraph('Calculated Units:'));
            append(doc, Paragraph('High Frequency Results:'));


            if high_freq_enabled
                outputData1 = {...
                'Parameter', 'Value', 'Unit'; ...
                'High-Frequency Mode:', high_freq_enabled, " "; ...
                'Skin Depth', sprintf('%.2f', delta_microns), "µm"; ...
                'Recommendation', recommendation, " "; ...
                'Effective Area', sprintf('%.2f', A_effective_mm2), "mm^2"; ...
                'Additional Remarks', 'Consider wider traces or thicker copper to mitigate skin effect', " "; ...
                };


            outputTable1 = Table(outputData1);
            outputTable1.Border = 'solid';
            outputTable1.Style = {Width('100%')}; % Ensures full width
            append(doc, outputTable1);
            end


            append(doc, Paragraph(' ')); % Empty paragraph for spacing
            append(doc, Paragraph('Trace Width Results:'));


            outputData2 = {...
                'Parameter', 'Value', 'Unit'; ...
                'Recommended Trace Width (basic)', sprintf('%.2f', trace_width_mils), "mils"; ...
                'Recommended Trace Width (High Frequency)', sprintf('%.2f', final_trace_width_mils), "mils"; ...
                };


            outputTable2 = Table(outputData2);
            outputTable2.Border = 'solid';
            outputTable2.Style = {Width('100%')}; % Ensures full width
            append(doc, outputTable2);


            % Close and save the document
            close(doc);
        
            % Notify user
            msgbox(['PDF report saved as: ' pdfFileName], 'Success');
        end

        % Button pushed function: GeneratePDFButton_4
        function GeneratePDFButton_4Pushed(app, event)
            % User inputs from app components
            current = app.Current.Value;                % Current in Amps
            copper_thickness = app.CopperThickness.Value;      % Copper thickness in oz/ft²
            trace_length_mm = app.TraceLength.Value;        % Length in mm
            trace_width_mils = app.TraceWidthField.Value;        % Trace width in mils
            trace_type = app.LayerType.Value;                % 'Internal' or 'External'
            high_freq_enabled = app.HighFreqBox.Value;
            frequency_MHz = app.Frequency.Value; % MHz
            
            % Conversion constants
            mil_to_meter = 25.4e-6;
            frequency_Hz = frequency_MHz * 1e6;


            % Convert user inputs to meters
            trace_length_m = trace_length_mm / 1000; % mm to meters
            trace_width_m = trace_width_mils * mil_to_meter;
            
            % Convert copper weight (oz/ft²) to thickness in mils then meters
            thickness_mils = 1.37 * copper_thickness; % mils
            trace_thickness_m = thickness_mils * mil_to_meter; % meters
            
            % Cross-sectional area of the trace in m²
            A = trace_width_m * trace_thickness_m;
            A_mm2 = A * 1e+06;
            
            % Validate area
            if A_mm2 == 0
                uialert(app.UIFigure, 'Error: Trace width or thickness cannot be zero.', 'Error');
                return;
            end
            
            % Copper resistivity
            copper_resistivity = 1.68e-8; % Ω·m


            if high_freq_enabled
                mu0 = 4 * pi * 1e-7;    % H/m
                rho_copper = 1.68e-8;   % Ω·m


                switch trace_type
                case 'Internal'
                    mu_eff = mu0 * 1.1;
                case 'External'
                    mu_eff = mu0;
                otherwise
                    uialert(app.UIFigure, 'Unknown action selected!', 'Error');
                    return;
               end


                delta = sqrt(2 * rho_copper / (mu_eff * 2 * pi * frequency_Hz)); % in meters
                % Effective thickness is minimum of actual thickness and skin depth
                effective_thickness_m = min(trace_thickness_m, delta);
                % Effective area with skin effect consideration
                A_eff = trace_width_m * effective_thickness_m;
                A_eff_mm2 = A_eff * 1e6;
                
                % Recalculate resistance
                R_skin = copper_resistivity * (trace_length_m / A_eff);
                R_skin_mo = R_skin * 1e3;


                % Voltage drop & power loss with skin effect resistance
                Vdrop_skin = current * R_skin;
                Vdrop_skin_mv = Vdrop_skin * 1e3;
                PowerLoss_skin = current^2 * R_skin;
            end


            % Apply derating for internal/external traces
            switch trace_type
                case 'Internal'
                    derating_factor = 0.8; % Internal traces need derating due to less cooling
                case 'External'
                    derating_factor = 1.0; % External traces are better cooled
                otherwise
                    uialert(app.UIFigure, 'Unknown action selected!', 'Error');
                    return;
            end
            
            % Calculate resistance (Ohms)
            R = copper_resistivity * (trace_length_m / A);
            
            % Apply derating if internal trace
            R = R / derating_factor; 
            R_mo = R * 1e3;
            
            % Voltage drop (V)
            Vdrop = current * R;
            Vdrop_mv = Vdrop * 1e3;
            
            % Power loss (W)
            PowerLoss = current^2 * R;
            
            % Display results in the app
            % Initialize output as a cell array
            outputText = {
                sprintf('--- Trace Electrical Properties ---')
                sprintf('Trace Type: %s', trace_type)
                sprintf('Cross-sectional Area (Basic): %.6e mm²', A_mm2)
                sprintf('Resistance (Basic): %.6f mΩ', R_mo)
            };
            
            % Add high-frequency values if enabled
            if high_freq_enabled
                outputText = [outputText; {  % Append new lines to the cell array
                    sprintf('High Frequency Mode: Enabled')
                    sprintf('Cross-sectional Area (High Frequency): %.6e mm²', A_eff_mm2)
                    sprintf('Resistance (High Frequency): %.6f mΩ', R_skin_mo)
                }];
            end

            % Assign to UI component
            app.Resistance.Value = outputText;


            outputText1 = {
                sprintf('--- Trace Electrical Properties ---')
                sprintf('Trace Type: %s', trace_type)
                sprintf('Voltage Drop (Basic): %.4f mV', Vdrop_mv)
                sprintf('Power Loss (Basic): %.4f W', PowerLoss)
            };
            
            % Add high-frequency values if enabled
            if high_freq_enabled
                outputText1 = [outputText1; {  % Append new lines to the cell array
                    sprintf('High Frequency Mode: Enabled')
                    sprintf('Voltage Drop (High Frequency): %.4f mV', Vdrop_skin_mv)
                    sprintf('Power Loss (High Frequency): %.4f W', PowerLoss_skin)
                }];
            end

            % Assign to UI component
            app.VoltageDropandPowerLoss.Value = outputText1;


             % Create a unique filename with timestamp
            timestamp = datestr(now, 'yyyy-mm-dd_HH-MM-SS');
            pdfFileName = ['Voltage_Drop_Calculation_Report_' timestamp '.pdf'];
        
            % Create PDF document
            import mlreportgen.dom.*;
            doc = Document(pdfFileName, 'pdf');
        
            % Title
            titlePara = Paragraph('Voltage Drop Calculation Report');
            titlePara.Bold = true;
            titlePara.FontSize = '14pt';
            append(doc, titlePara);
            append(doc, HorizontalRule());


            % Input Parameters Table
            append(doc, Paragraph('Input Parameters:'));
            
            inputData = {...
                'Parameter', 'Value', 'Unit'; ...
                'Current', sprintf('%.2f', current), "A"; ...
                'Copper Thickness', sprintf('%.2f', copper_thickness), "oz/sq ft"; ...
                'Trace Length', sprintf('%.2f', trace_length_mm), "mm"; ...
                'Trace Width', sprintf('%.2f', trace_width_mils), "mils"; ...
                'Layer Type', trace_type, " "; ...
                'High Frequency PCB', high_freq_enabled , " "; ...
                'frequency', sprintf('%.2f', frequency_MHz), "MHz"};
            
            inputTable = Table(inputData);
            inputTable.Border = 'solid';
            inputTable.Style = {Width('100%')}; % Ensures full width
            append(doc, inputTable);
            
            % Output Section
            append(doc, Paragraph('Calculated Units:'));
            append(doc, Paragraph('High Frequency Results:'));


            if high_freq_enabled
                outputData1 = {...
                'Parameter', 'Value', 'Unit'; ...
                'High-Frequency Mode:', high_freq_enabled, " "; ...
                'Cross-sectional Area (High Frequency):', sprintf('%.3e', A_eff_mm2), "mm²"; ...
                'Resistance (High Frequency):', sprintf('%.2f', R_skin_mo), "mΩ"; ...
                'Voltage Drop (High Frequency):', sprintf('%.2f', Vdrop_skin_mv), "mV"; ...
                'Power Loss (High Frequency):', sprintf('%.2f', PowerLoss_skin), "W"; ...
                };


            outputTable1 = Table(outputData1);
            outputTable1.Border = 'solid';
            outputTable1.Style = {Width('100%')}; % Ensures full width
            append(doc, outputTable1);
            end


            append(doc, Paragraph(' ')); % Empty paragraph for spacing
            append(doc, Paragraph('Voltage Drop and Power Loss Results:'));


            outputData2 = {...
                'Parameter', 'Value', 'Unit'; ...
                'Trace Type:', trace_type , " "; ...
                'Cross-sectional Area (Basic):', sprintf('%.3e', A_mm2) , "mm²"; ...
                'Resistance (Basic):', sprintf('%.2f', R_mo) , "mΩ"; ...
                'Voltage Drop (Basic):', sprintf('%.2f', Vdrop_mv) , "mV"; ...
                'Power Loss (Basic):', sprintf('%.2f', PowerLoss) , "W"; ...
                };


            outputTable2 = Table(outputData2);
            outputTable2.Border = 'solid';
            outputTable2.Style = {Width('100%')}; % Ensures full width
            append(doc, outputTable2);


            % Close and save the document
            close(doc);
        
            % Notify user
            msgbox(['PDF report saved as: ' pdfFileName], 'Success');


        end

        % Button pushed function: GeneratePDFButton_5
        function GeneratePDFButton_5Pushed(app, event)
            % User Inputs
            trace_width_mils = app.TraceWidthinmilsEditField.Value;  % in mils
            trace_thickness_oz = app.TraceThicknessinozsqftEditField.Value;  % in oz/ft²
            temp_rise = app.AllowedTemperatureRiseinCEditField.Value; % in °C
            is_internal = app.TraceTypeDropDown_2.Value; % 'Internal' or 'External'
            frequency_MHz = app.Frequency2.Value; % Frequency in MHz
            
            % Convert trace width to mm
            trace_width_mm = trace_width_mils * 0.0254; 
            
            % Convert thickness from oz/ft² to mm (1 oz/ft² ≈ 35 μm)
            trace_thickness_mm = trace_thickness_oz * 35 * 1e-3; 
            
            % IPC-2221 Equations
            if strcmp(is_internal, 'Internal')
                k = 0.024;  % Internal trace constant
                b = 0.44;
                c = 0.725;
            else
                k = 0.048;  % External trace constant
                b = 0.44;
                c = 0.725;
            end
            
            % Calculate current capacity (I) using IPC-2221 formula:
            I_max = k * (trace_width_mm ^ b) * (trace_thickness_mm ^ c) * (temp_rise ^ 0.44);
        

            % Calculate Skin Depth (in mm)
            f_Hz = frequency_MHz * 1e6;  % Convert MHz to Hz
            mu_0 = 4 * pi * 1e-7; % Permeability of free space
            sigma = 5.8e7; % Conductivity of copper (S/m)
            
            delta_m = 1 / sqrt(pi * f_Hz * mu_0 * sigma); % Skin depth in meters
            delta_um = delta_m * 1e6; % Convert to microns for display
        
            % Adjust Effective Thickness Due to Skin Effect
            %if trace_thickness_mm > delta_m
               % effective_thickness_mm = delta_m; % Only the skin depth contributes
            %else
              %  effective_thickness_mm = trace_thickness_mm; % Use full thickness at low freq
           % end


            %min_thickness_mm = 1e-6; % Minimum threshold (1 micron)
            
           % if trace_thickness_mm > delta_m
                %effective_thickness_mm = max(delta_m, min_thickness_mm); % Use skin depth but ensure minimum value
            %else
               % effective_thickness_mm = max(trace_thickness_mm, min_thickness_mm); % Use full thickness at low frequency
            %end


            app.CurrentCapacityTextArea.Value = sprintf( ...
            "Estimated Max Current Capacity: %.2f A\nSkin Depth at %.2f MHz: %.2f µm\n", ...
            I_max, frequency_MHz, delta_um);


            % Create a unique filename with timestamp
            timestamp = datestr(now, 'yyyy-mm-dd_HH-MM-SS');
            pdfFileName = ['Current_Carrying_Capacity_Report' timestamp '.pdf'];
        
            % Create PDF document
            import mlreportgen.dom.*;
            doc = Document(pdfFileName, 'pdf');
        
            % Title
            titlePara = Paragraph('Current Carrying Capacity Report');
            titlePara.Bold = true;
            titlePara.FontSize = '14pt';
            append(doc, titlePara);
            append(doc, HorizontalRule());


            % Input Parameters Table
            append(doc, Paragraph('Input Parameters:'));
            
            inputData = {...
                'Parameter', 'Value', 'Unit'; ...
                'Trace Width', sprintf('%.2f', trace_width_mils), "mils"; ...
                'Trace Thickness', sprintf('%.2f', trace_thickness_oz), "oz/sq ft"; ...
                'Trace Type', is_internal, " "; ...
                'Allowed Temperature Rise', sprintf('%.2f', temp_rise), "°C"; ...
                'frequency', sprintf('%.2f', frequency_MHz), "MHz"};
            
            inputTable = Table(inputData);
            inputTable.Border = 'solid';
            inputTable.Style = {Width('100%')}; % Ensures full width
            append(doc, inputTable);
            
            % Output Section
            append(doc, Paragraph('Calculated Units:'));


                outputData = {...
                'Parameter', 'Value', 'Unit'; ...
                'Trace Type:', is_internal , " "; ...
                'Estimated Max Current Capacity:', sprintf('%.2f', I_max) , "A"; ...
                'Skin Depth:', sprintf('%.2f', delta_um) , "µm"; ...
                };


            outputTable = Table(outputData);
            outputTable.Border = 'solid';
            outputTable.Style = {Width('100%')}; % Ensures full width
            append(doc, outputTable);


            % Close and save the document
            close(doc);
        
            % Notify user
            msgbox(['PDF report saved as: ' pdfFileName], 'Success');
        end

        % Button pushed function: UserGuideButton
        function UserGuideButtonPushed(app, event)
            open('User_Guide.pdf'); % Opens the PDF guide when clicked
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GraphAnalyserResult = uipanel(app.UIFigure);
            app.GraphAnalyserResult.Title = 'Graph Analyser Result';
            app.GraphAnalyserResult.Visible = 'off';
            app.UIAxes = uiaxes(app.GraphAnalyserResult);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.BackButton_10 = uibutton(app.GraphAnalyserResult, 'push');
            app.BackButton_10.ButtonPushedFcn = createCallbackFcn(app, @BackButton_10Pushed, true);
            app.BackButton_10.Text = 'Back';
            app.HomeButton_8 = uibutton(app.GraphAnalyserResult, 'push');
            app.HomeButton_8.ButtonPushedFcn = createCallbackFcn(app, @HomeButton_8Pushed, true);
            app.HomeButton_8.Text = 'Home';
            app.GraphAnalyser = uipanel(app.UIFigure);
            app.GraphAnalyser.Title = 'Graph Analyser Panel';
            app.GraphAnalyser.Visible = 'off';
            app.Image_11 = uiimage(app.GraphAnalyser);
            app.Image_11.ImageSource = fullfile(pathToMLAPP, 'wallpaper bg', 'Screenshot 2025-03-31 003016.png');
            app.Title = uilabel(app.GraphAnalyser);
            app.Title.Text = 'What do you wish to plot?';
            app.TypeofGraphDropDownLabel = uilabel(app.GraphAnalyser);
            app.TypeofGraphDropDownLabel.Text = 'Type of Graph';
            app.GraphTypeDropDown = uidropdown(app.GraphAnalyser);
            app.GraphTypeDropDown.Items = {'Impedance vs Frequency', 'Skin Depth vs Frequency', 'Voltage Drop vs Current', 'Power Loss vs Trace Width', 'Effective Trace Thickness vs Frequency'};
            app.GraphTypeDropDown.Value = 'Impedance vs Frequency';
            app.PlotButton = uibutton(app.GraphAnalyser, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot Graph';
            app.BackButton_9 = uibutton(app.GraphAnalyser, 'push');
            app.BackButton_9.ButtonPushedFcn = createCallbackFcn(app, @BackButton_9Pushed, true);
            app.BackButton_9.Text = 'Back';
            app.CurrentCarrying = uipanel(app.UIFigure);
            app.CurrentCarrying.Title = 'Current Carrying Capacity Panel';
            app.CurrentCarrying.Visible = 'off';
            app.Image_10 = uiimage(app.CurrentCarrying);
            app.Image_10.ImageSource = fullfile(pathToMLAPP, 'wallpaper bg', 'Screenshot 2025-03-31 003016.png');
            app.EnterTheValuesGivenBelowLabel = uilabel(app.CurrentCarrying);
            app.EnterTheValuesGivenBelowLabel.Text = 'Enter The Values Given Below';
            app.TraceWidthinmilsEditFieldLabel = uilabel(app.CurrentCarrying);
            app.TraceWidthinmilsEditFieldLabel.Text = 'Trace Width (in mils)';
            app.TraceWidthinmilsEditField = uieditfield(app.CurrentCarrying, 'numeric');
            app.TraceThicknessinozsqftEditFieldLabel = uilabel(app.CurrentCarrying);
            app.TraceThicknessinozsqftEditFieldLabel.Text = 'Trace Thickness (in oz/sq ft)';
            app.TraceThicknessinozsqftEditField = uieditfield(app.CurrentCarrying, 'numeric');
            app.TraceLengthinmmEditFieldLabel = uilabel(app.CurrentCarrying);
            app.TraceLengthinmmEditFieldLabel.Text = 'Trace Length (in mm)';
            app.TraceLengthinmmEditField = uieditfield(app.CurrentCarrying, 'numeric');
            app.TraceTypeDropDown_2Label = uilabel(app.CurrentCarrying);
            app.TraceTypeDropDown_2Label.Text = 'Trace Type';
            app.TraceTypeDropDown_2 = uidropdown(app.CurrentCarrying);
            app.TraceTypeDropDown_2.Items = {'Internal', 'External'};
            app.TraceTypeDropDown_2.Value = 'Internal';
            app.AllowedTemperatureRiseinCEditFieldLabel = uilabel(app.CurrentCarrying);
            app.AllowedTemperatureRiseinCEditFieldLabel.Text = 'Allowed Temperature Rise (in °C)';
            app.AllowedTemperatureRiseinCEditField = uieditfield(app.CurrentCarrying, 'numeric');
            app.FrequencyinMHzLabel = uilabel(app.CurrentCarrying);
            app.FrequencyinMHzLabel.Text = 'Frequency (in MHz)';
            app.Frequency2 = uieditfield(app.CurrentCarrying, 'numeric');
            app.Calculate2 = uibutton(app.CurrentCarrying, 'push');
            app.Calculate2.ButtonPushedFcn = createCallbackFcn(app, @Calculate2ButtonPushed, true);
            app.Calculate2.Text = 'Calculate';
            app.CurrentCapacityTextAreaLabel = uilabel(app.CurrentCarrying);
            app.CurrentCapacityTextAreaLabel.Text = 'Current Capacity';
            app.CurrentCapacityTextArea = uitextarea(app.CurrentCarrying);
            app.BackButton_4 = uibutton(app.CurrentCarrying, 'push');
            app.BackButton_4.ButtonPushedFcn = createCallbackFcn(app, @BackButton_4Pushed, true);
            app.BackButton_4.Text = 'Back';
            app.HomeButton_3 = uibutton(app.CurrentCarrying, 'push');
            app.HomeButton_3.ButtonPushedFcn = createCallbackFcn(app, @HomeButton_3Pushed, true);
            app.HomeButton_3.Text = 'Home';
            app.GeneratePDFButton_5 = uibutton(app.CurrentCarrying, 'push');
            app.GeneratePDFButton_5.ButtonPushedFcn = createCallbackFcn(app, @GeneratePDFButton_5Pushed, true);
            app.GeneratePDFButton_5.Text = 'Generate PDF';
            app.VoltageDropPanel = uipanel(app.UIFigure);
            app.VoltageDropPanel.Title = 'Voltage Drop Panel';
            app.VoltageDropPanel.Visible = 'off';
            app.Image_9 = uiimage(app.VoltageDropPanel);
            app.Image_9.ImageSource = fullfile(pathToMLAPP, 'wallpaper bg', 'Screenshot 2025-03-31 003016.png');
            app.Parameters = uilabel(app.VoltageDropPanel);
            app.Parameters.Text = 'Enter the below parameters';
            app.CurrentAEditFieldLabel_2 = uilabel(app.VoltageDropPanel);
            app.CurrentAEditFieldLabel_2.Text = 'Current (A)';
            app.Current = uieditfield(app.VoltageDropPanel, 'numeric');
            app.CopperThicknessozsqftLabel_2 = uilabel(app.VoltageDropPanel);
            app.CopperThicknessozsqftLabel_2.Text = 'Copper Thickness (oz/sq ft)';
            app.CopperThickness = uieditfield(app.VoltageDropPanel, 'numeric');
            app.TraceLengthLabel = uilabel(app.VoltageDropPanel);
            app.TraceLengthLabel.Text = 'Trace Length (mm)';
            app.TraceLength = uieditfield(app.VoltageDropPanel, 'numeric');
            app.LayerTypeDropDown_2Label = uilabel(app.VoltageDropPanel);
            app.LayerTypeDropDown_2Label.Text = 'Layer Type';
            app.LayerType = uidropdown(app.VoltageDropPanel);
            app.LayerType.Items = {'Internal', 'External'};
            app.LayerType.Value = 'Internal';
            app.HighFreqBox = uicheckbox(app.VoltageDropPanel);
            app.HighFreqBox.Text = 'High-Frequency PCB Option';
            app.Calculate = uibutton(app.VoltageDropPanel, 'push');
            app.Calculate.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed2, true);
            app.Calculate.Text = 'Calculate';
            app.VoltageDropandPowerLossTextAreaLabel = uilabel(app.VoltageDropPanel);
            app.VoltageDropandPowerLossTextAreaLabel.Text = 'Voltage Drop and Power Loss';
            app.VoltageDropandPowerLoss = uitextarea(app.VoltageDropPanel);
            app.FrequencyMHzEditFieldLabel_2 = uilabel(app.VoltageDropPanel);
            app.FrequencyMHzEditFieldLabel_2.Text = 'Frequency (MHz)';
            app.Frequency = uieditfield(app.VoltageDropPanel, 'numeric');
            app.ResistanceLabel = uilabel(app.VoltageDropPanel);
            app.ResistanceLabel.Text = 'Resistance';
            app.Resistance = uitextarea(app.VoltageDropPanel);
            app.TraceWidthmilsEditFieldLabel = uilabel(app.VoltageDropPanel);
            app.TraceWidthmilsEditFieldLabel.Text = 'Trace Width (mils)';
            app.TraceWidthField = uieditfield(app.VoltageDropPanel, 'numeric');
            app.BackButton_5 = uibutton(app.VoltageDropPanel, 'push');
            app.BackButton_5.ButtonPushedFcn = createCallbackFcn(app, @BackButton_5Pushed, true);
            app.BackButton_5.Text = 'Back';
            app.HomeButton_4 = uibutton(app.VoltageDropPanel, 'push');
            app.HomeButton_4.ButtonPushedFcn = createCallbackFcn(app, @HomeButton_4Pushed, true);
            app.HomeButton_4.Text = 'Home';
            app.GeneratePDFButton_4 = uibutton(app.VoltageDropPanel, 'push');
            app.GeneratePDFButton_4.ButtonPushedFcn = createCallbackFcn(app, @GeneratePDFButton_4Pushed, true);
            app.GeneratePDFButton_4.Text = 'Generate PDF';
            app.TraceWidthPanel = uipanel(app.UIFigure);
            app.TraceWidthPanel.Title = 'Trace Width Panel';
            app.TraceWidthPanel.Visible = 'off';
            app.Image_8 = uiimage(app.TraceWidthPanel);
            app.Image_8.ImageSource = fullfile(pathToMLAPP, 'wallpaper bg', 'Screenshot 2025-03-31 003016.png');
            app.ParametersLabel = uilabel(app.TraceWidthPanel);
            app.ParametersLabel.Text = 'Enter the below parameters';
            app.CurrentAEditFieldLabel = uilabel(app.TraceWidthPanel);
            app.CurrentAEditFieldLabel.Text = 'Current (A)';
            app.CurrentEditField = uieditfield(app.TraceWidthPanel, 'numeric');
            app.CopperThicknessozsqftLabel = uilabel(app.TraceWidthPanel);
            app.CopperThicknessozsqftLabel.Text = 'Copper Thickness (oz/sq ft)';
            app.CopperThicknessEditField = uieditfield(app.TraceWidthPanel, 'numeric');
            app.TemperatureRiseCEditFieldLabel = uilabel(app.TraceWidthPanel);
            app.TemperatureRiseCEditFieldLabel.Text = 'Temperature Rise (°C)';
            app.TempRiseEditField = uieditfield(app.TraceWidthPanel, 'numeric');
            app.LayerTypeDropDownLabel = uilabel(app.TraceWidthPanel);
            app.LayerTypeDropDownLabel.Text = 'Layer Type';
            app.LayerTypeDropDown = uidropdown(app.TraceWidthPanel);
            app.LayerTypeDropDown.Items = {'Internal', 'External'};
            app.LayerTypeDropDown.Value = 'Internal';
            app.HighFreqCheckBox = uicheckbox(app.TraceWidthPanel);
            app.HighFreqCheckBox.Text = 'High-Frequency PCB Option';
            app.CalculateButton = uibutton(app.TraceWidthPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.TraceWidthResultsTextAreaLabel = uilabel(app.TraceWidthPanel);
            app.TraceWidthResultsTextAreaLabel.Text = 'Trace Width Results';
            app.TraceWidthResultsTextArea = uitextarea(app.TraceWidthPanel);
            app.FrequencyMHzEditFieldLabel = uilabel(app.TraceWidthPanel);
            app.FrequencyMHzEditFieldLabel.Text = 'Frequency (MHz)';
            app.FrequencyEditField = uieditfield(app.TraceWidthPanel, 'numeric');
            app.HighFrequencyResultsTextAreaLabel = uilabel(app.TraceWidthPanel);
            app.HighFrequencyResultsTextAreaLabel.Text = 'High Frequency Results';
            app.HFResultsTextArea = uitextarea(app.TraceWidthPanel);
            app.BackButton_6 = uibutton(app.TraceWidthPanel, 'push');
            app.BackButton_6.ButtonPushedFcn = createCallbackFcn(app, @BackButton_6Pushed, true);
            app.BackButton_6.Text = 'Back';
            app.HomeButton_5 = uibutton(app.TraceWidthPanel, 'push');
            app.HomeButton_5.ButtonPushedFcn = createCallbackFcn(app, @HomeButton_5Pushed, true);
            app.HomeButton_5.Text = 'Home';
            app.GeneratePDFButton_3 = uibutton(app.TraceWidthPanel, 'push');
            app.GeneratePDFButton_3.ButtonPushedFcn = createCallbackFcn(app, @GeneratePDFButton_3Pushed, true);
            app.GeneratePDFButton_3.Text = 'Generate PDF';
            app.TraceProperties = uipanel(app.UIFigure);
            app.TraceProperties.Title = 'Trace Properties Panel';
            app.TraceProperties.Visible = 'off';
            app.Image_7 = uiimage(app.TraceProperties);
            app.Image_7.ImageSource = fullfile(pathToMLAPP, 'wallpaper bg', 'Screenshot 2025-03-31 003016.png');
            app.YouhavechosenthefollowingsubstrateTextAreaLabel_2 = uilabel(app.TraceProperties);
            app.YouhavechosenthefollowingsubstrateTextAreaLabel_2.Text = 'You have chosen the following substrate';
            app.SubstrateConf_TP = uitextarea(app.TraceProperties);
            app.SubstrateConf_TP.Value = {'You have chosen the following substrate'};
            app.TraceWidth = uibutton(app.TraceProperties, 'push');
            app.TraceWidth.ButtonPushedFcn = createCallbackFcn(app, @TraceWidthButtonPushed, true);
            app.TraceWidth.Text = 'Advanced Trace Width Calculations';
            app.VoltageDrop = uibutton(app.TraceProperties, 'push');
            app.VoltageDrop.ButtonPushedFcn = createCallbackFcn(app, @VoltageDropButtonPushed, true);
            app.VoltageDrop.Text = 'Voltage Drop & Power Loss Calculator';
            app.question = uilabel(app.TraceProperties);
            app.question.Text = 'What do you wish to perform?';
            app.Currentcarrying = uibutton(app.TraceProperties, 'push');
            app.Currentcarrying.ButtonPushedFcn = createCallbackFcn(app, @CurrentcarryingButtonPushed, true);
            app.Currentcarrying.Text = 'Current carrying capacity estimator';
            app.BackButton_3 = uibutton(app.TraceProperties, 'push');
            app.BackButton_3.ButtonPushedFcn = createCallbackFcn(app, @BackButton_3Pushed, true);
            app.BackButton_3.Text = 'Back';
            app.HomeButton_2 = uibutton(app.TraceProperties, 'push');
            app.HomeButton_2.ButtonPushedFcn = createCallbackFcn(app, @HomeButton_2Pushed, true);
            app.HomeButton_2.Text = 'Home';
            app.ImpedanceCalculator = uipanel(app.UIFigure);
            app.ImpedanceCalculator.Title = 'Impedance Calculator Panel';
            app.ImpedanceCalculator.Visible = 'off';
            app.Image_6 = uiimage(app.ImpedanceCalculator);
            app.Image_6.ImageSource = fullfile(pathToMLAPP, 'wallpaper bg', 'Screenshot 2025-03-31 003016.png');
            app.YouhavechosenthefollowingsubstrateTextAreaLabel_3 = uilabel(app.ImpedanceCalculator);
            app.YouhavechosenthefollowingsubstrateTextAreaLabel_3.Text = 'You have chosen the following substrate';
            app.SubConf = uitextarea(app.ImpedanceCalculator);
            app.TraceWidthinmilsEditField_2Label = uilabel(app.ImpedanceCalculator);
            app.TraceWidthinmilsEditField_2Label.Text = 'Trace Width (in mils)';
            app.TraceWidthmils = uieditfield(app.ImpedanceCalculator, 'numeric');
            app.TraceThicknessinozsqftEditField_2Label = uilabel(app.ImpedanceCalculator);
            app.TraceThicknessinozsqftEditField_2Label.Text = 'Trace Thickness (in oz/sq ft)';
            app.TraceThicknessozsqft = uieditfield(app.ImpedanceCalculator, 'numeric');
            app.HeightofSubstrateinmmEditFieldLabel = uilabel(app.ImpedanceCalculator);
            app.HeightofSubstrateinmmEditFieldLabel.Text = 'Height of Substrate (in mm)';
            app.HeightofSubstrateinmm = uieditfield(app.ImpedanceCalculator, 'numeric');
            app.FrequencyinMHzEditFieldLabel = uilabel(app.ImpedanceCalculator);
            app.FrequencyinMHzEditFieldLabel.Text = 'Frequency (in MHz)';
            app.FrequencyinMHzEditField = uieditfield(app.ImpedanceCalculator, 'numeric');
            app.TraceTypeDropDownLabel = uilabel(app.ImpedanceCalculator);
            app.TraceTypeDropDownLabel.Text = 'Trace Type';
            app.TraceTypeDropDown = uidropdown(app.ImpedanceCalculator);
            app.TraceTypeDropDown.Items = {'Microstrip', 'Stripline'};
            app.TraceTypeDropDown.Value = 'Microstrip';
            app.CalculateButton_2 = uibutton(app.ImpedanceCalculator, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.ImpedanceResultsTextAreaLabel = uilabel(app.ImpedanceCalculator);
            app.ImpedanceResultsTextAreaLabel.Text = 'Impedance Results';
            app.ImpedanceResultsTextArea = uitextarea(app.ImpedanceCalculator);
            app.BackButton_2 = uibutton(app.ImpedanceCalculator, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackButton_2Pushed, true);
            app.BackButton_2.Text = 'Back';
            app.HomeButton = uibutton(app.ImpedanceCalculator, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.GeneratePDFButton_2 = uibutton(app.ImpedanceCalculator, 'push');
            app.GeneratePDFButton_2.ButtonPushedFcn = createCallbackFcn(app, @GeneratePDFButton_2Pushed, true);
            app.GeneratePDFButton_2.Text = 'Generate PDF';
            app.UnitConverterPanel = uipanel(app.UIFigure);
            app.UnitConverterPanel.Title = 'Unit Converter Panel';
            app.UnitConverterPanel.Visible = 'off';
            app.Image_5 = uiimage(app.UnitConverterPanel);
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'wallpaper bg', 'Screenshot 2025-03-31 003016.png');
            app.EnterthegivenvaluesLabel = uilabel(app.UnitConverterPanel);
            app.EnterthegivenvaluesLabel.Text = 'Enter the given values';
            app.TraceWidthEditFieldLabel = uilabel(app.UnitConverterPanel);
            app.TraceWidthEditFieldLabel.Text = 'Trace Width';
            app.TraceWidthEditField = uieditfield(app.UnitConverterPanel, 'numeric');
            app.WidthDropDown = uidropdown(app.UnitConverterPanel);
            app.WidthDropDown.Items = {'mils', 'mm', 'inches'};
            app.WidthDropDown.Value = 'mils';
            app.TraceThicknessEditFieldLabel = uilabel(app.UnitConverterPanel);
            app.TraceThicknessEditFieldLabel.Text = 'Trace Thickness ';
            app.TraceThicknessEditField = uieditfield(app.UnitConverterPanel, 'numeric');
            app.ThicknessDropDown = uidropdown(app.UnitConverterPanel);
            app.ThicknessDropDown.Items = {'oz/sq ft', 'mm', 'mils'};
            app.ThicknessDropDown.Value = 'oz/sq ft';
            app.TraceLengthEditFieldLabel = uilabel(app.UnitConverterPanel);
            app.TraceLengthEditFieldLabel.Text = 'Trace Length';
            app.TraceLengthEditField = uieditfield(app.UnitConverterPanel, 'numeric');
            app.LengthDropDown = uidropdown(app.UnitConverterPanel);
            app.LengthDropDown.Items = {'mm', 'mils', 'inches'};
            app.LengthDropDown.Value = 'mm';
            app.ConvertButton = uibutton(app.UnitConverterPanel, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
            app.ResultTextAreaLabel = uilabel(app.UnitConverterPanel);
            app.ResultTextAreaLabel.Text = 'Result';
            app.ResultTextArea = uitextarea(app.UnitConverterPanel);
            app.BackButton_11 = uibutton(app.UnitConverterPanel, 'push');
            app.BackButton_11.ButtonPushedFcn = createCallbackFcn(app, @BackButton_11Pushed, true);
            app.BackButton_11.Text = 'Back';
            app.GeneratePDFButton = uibutton(app.UnitConverterPanel, 'push');
            app.GeneratePDFButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePDFButtonPushed, true);
            app.GeneratePDFButton.Text = 'GeneratePDF';
            app.ResoFreqResult = uipanel(app.UIFigure);
            app.ResoFreqResult.Title = 'Resonant Frequency Result Panel';
            app.ResoFreqResult.Visible = 'off';
            app.Image_4 = uiimage(app.ResoFreqResult);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'wallpaper bg', 'Screenshot 2025-03-31 003016.png');
            app.TheResonantFrequencyObtainedisTextAreaLabel = uilabel(app.ResoFreqResult);
            app.TheResonantFrequencyObtainedisTextAreaLabel.Text = 'The Resonant Frequency Obtained is';
            app.Result = uitextarea(app.ResoFreqResult);
            app.BackButton_8 = uibutton(app.ResoFreqResult, 'push');
            app.BackButton_8.ButtonPushedFcn = createCallbackFcn(app, @BackButton_8Pushed, true);
            app.BackButton_8.Text = 'Back';
            app.HomeButton_7 = uibutton(app.ResoFreqResult, 'push');
            app.HomeButton_7.ButtonPushedFcn = createCallbackFcn(app, @HomeButton_7Pushed, true);
            app.HomeButton_7.Text = 'Home';
            app.GenerateButton = uibutton(app.ResoFreqResult, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate PDF';
            app.ResonantFrequencyPanel = uipanel(app.UIFigure);
            app.ResonantFrequencyPanel.Title = 'Resonant Frequency Panel';
            app.ResonantFrequencyPanel.Visible = 'off';
            app.Image_3 = uiimage(app.ResonantFrequencyPanel);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'wallpaper bg', 'Screenshot 2025-03-31 003016.png');
            app.YouhavechosenthefollowingsubstrateTextAreaLabel = uilabel(app.ResonantFrequencyPanel);
            app.YouhavechosenthefollowingsubstrateTextAreaLabel.Text = 'You have chosen the following substrate';
            app.SubstrateConf = uitextarea(app.ResonantFrequencyPanel);
            app.SubstrateConf.Value = {'You have chosen the following substrate'};
            app.LengthofthemicrostriplineinmmLLabel = uilabel(app.ResonantFrequencyPanel);
            app.LengthofthemicrostriplineinmmLLabel.Text = 'Length of the microstrip line in mm (L)';
            app.Length = uieditfield(app.ResonantFrequencyPanel, 'numeric');
            app.WidthofthemicrostriplineinmmWEditFieldLabel = uilabel(app.ResonantFrequencyPanel);
            app.WidthofthemicrostriplineinmmWEditFieldLabel.Text = 'Width of the microstrip line in mm (W)';
            app.Width = uieditfield(app.ResonantFrequencyPanel, 'numeric');
            app.HeightofthesubstrateinmmhEditFieldLabel = uilabel(app.ResonantFrequencyPanel);
            app.HeightofthesubstrateinmmhEditFieldLabel.Text = 'Height of the substrate in mm (h)';
            app.Height = uieditfield(app.ResonantFrequencyPanel, 'numeric');
            app.ConfirmButton = uibutton(app.ResonantFrequencyPanel, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.Confirmation = uilabel(app.ResonantFrequencyPanel);
            app.Confirmation.Text = 'Confirm the values to calculate resonant frequency of microstripline';
            app.BackButton_7 = uibutton(app.ResonantFrequencyPanel, 'push');
            app.BackButton_7.ButtonPushedFcn = createCallbackFcn(app, @BackButton_7Pushed, true);
            app.BackButton_7.Text = 'Back';
            app.HomeButton_6 = uibutton(app.ResonantFrequencyPanel, 'push');
            app.HomeButton_6.ButtonPushedFcn = createCallbackFcn(app, @HomeButton_6Pushed, true);
            app.HomeButton_6.Text = 'Home';
            app.MaterialSelectorPanel = uipanel(app.UIFigure);
            app.MaterialSelectorPanel.Title = 'Material Selector Panel';
            app.MaterialSelectorPanel.Visible = 'off';
            app.Image_2 = uiimage(app.MaterialSelectorPanel);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'wallpaper bg', 'Screenshot 2025-03-31 003016.png');
            app.SubstrateSelection = uilabel(app.MaterialSelectorPanel);
            app.SubstrateSelection.Text = 'What is your substrate material? ';
            app.ListBox = uilistbox(app.MaterialSelectorPanel);
            app.ListBox.Items = {'FR-4', 'Rogers RO4350B', 'Alumina Ceramic', 'Teflon (PTFE)'};
            app.ListBox.Value = 'FR-4';
            app.SelectButton = uibutton(app.MaterialSelectorPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.BackButton = uibutton(app.MaterialSelectorPanel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.HomePanel = uipanel(app.UIFigure);
            app.HomePanel.Title = 'Home';
            app.Image = uiimage(app.HomePanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'wallpaper bg', 'Screenshot 2025-03-31 003016.png');
            app.PCBDesignToolLabel = uilabel(app.HomePanel);
            app.PCBDesignToolLabel.Text = 'PCB Design Tool';
            app.UnitConverterButton = uibutton(app.HomePanel, 'push');
            app.UnitConverterButton.ButtonPushedFcn = createCallbackFcn(app, @UnitConverterButtonPushed, true);
            app.UnitConverterButton.Text = 'Unit Converter';
            app.StartOperation = uilabel(app.HomePanel);
            app.StartOperation.Text = 'What operation do you wish to perform?';
            app.ResonantFrequencyCalculatorButton = uibutton(app.HomePanel, 'push');
            app.ResonantFrequencyCalculatorButton.ButtonPushedFcn = createCallbackFcn(app, @ResonantFrequencyCalculatorButtonPushed, true);
            app.ResonantFrequencyCalculatorButton.Text = 'Resonant Frequency Calculator';
            app.ImpedanceCalculatorButton = uibutton(app.HomePanel, 'push');
            app.ImpedanceCalculatorButton.ButtonPushedFcn = createCallbackFcn(app, @ImpedanceCalculatorButtonPushed, true);
            app.ImpedanceCalculatorButton.Text = 'Impedance Calculator';
            app.TracePropertiesButton = uibutton(app.HomePanel, 'push');
            app.TracePropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @TracePropertiesButtonPushed, true);
            app.TracePropertiesButton.Text = 'Trace Properties';
            app.GraphAnalyzerButton = uibutton(app.HomePanel, 'push');
            app.GraphAnalyzerButton.ButtonPushedFcn = createCallbackFcn(app, @GraphAnalyzerButtonPushed, true);
            app.GraphAnalyzerButton.Text = 'Graph Analyzer';
            app.UserGuideButton = uibutton(app.HomePanel, 'push');
            app.UserGuideButton.ButtonPushedFcn = createCallbackFcn(app, @UserGuideButtonPushed, true);
            app.UserGuideButton.Text = 'User Guide';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PCB_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end